classdef Sine_wave_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EditFieldVerticalShift    matlab.ui.control.NumericEditField
        EditFieldPhaseShift       matlab.ui.control.NumericEditField
        BaseLabel_5               matlab.ui.control.Label
        BaseLabel_4               matlab.ui.control.Label
        BaseLabel_3               matlab.ui.control.Label
        EditFieldFrequency        matlab.ui.control.NumericEditField
        BaseLabel_2               matlab.ui.control.Label
        EditFieldAmplitude        matlab.ui.control.NumericEditField
        BaseLabel                 matlab.ui.control.Label
        ColorButtonGroup          matlab.ui.container.ButtonGroup
        ScatterButton             matlab.ui.control.RadioButton
        CircleButton              matlab.ui.control.RadioButton
        GreenButton               matlab.ui.control.RadioButton
        RedButton                 matlab.ui.control.RadioButton
        BlueButton                matlab.ui.control.RadioButton
        PLOTLabel                 matlab.ui.control.Label
        PARAMETERSLabel           matlab.ui.control.Label
        SINEWAVEPLOTTERLabel      matlab.ui.control.Label
        FrequencySlider           matlab.ui.control.Slider
        FrequencySliderLabel      matlab.ui.control.Label
        VerticalShiftSlider       matlab.ui.control.Slider
        VerticalShiftSliderLabel  matlab.ui.control.Label
        AmplitudeSlider           matlab.ui.control.Slider
        AmplitudeSliderLabel      matlab.ui.control.Label
        PhaseShiftSlider          matlab.ui.control.Slider
        PhaseShiftSliderLabel     matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         Line          % Line object
    end


    methods (Access = private)
        
        function updateplot(app)
            
            % Define theta. Get a and b
            x = 0:0.01:10;
            frequency =app.FrequencySlider.Value;
            amplitude = app.AmplitudeSlider.Value;
            vertical_shift =app.VerticalShiftSlider.Value;
            phase_shift =app.PhaseShiftSlider.Value;
            
            % Define the values for the equation display
            app.EditFieldAmplitude.Value = amplitude;
            app.EditFieldFrequency.Value = frequency;
            app.EditFieldPhaseShift.Value = phase_shift;
            app.EditFieldVerticalShift.Value = vertical_shift;
            
            % Calculate y and plot it
            y = amplitude*sin(frequency*x + phase_shift) + vertical_shift;
            plotLine1=plot(app.UIAxes,x, y,'LineWidth',1);
            hold(app.UIAxes,"on");
            y_basic = sin(x);
            plotLine2=plot(app.UIAxes,x,y_basic,'r','LineWidth',1);
            hold(app.UIAxes,"off");
            legend(app.UIAxes,'Transformed','Basic');
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'y');
            grid(app.UIAxes,"minor");
            
            % Update the y-axis data
            plotLine1.YData = y;
            plotLine2.YData = y_basic;
            
            % Set line color  and update it
            if app.BlueButton.Value==1
                plotLine1.Color ='b';
                
            elseif app.RedButton.Value==1
                plotLine1.Color = 'r';
             
            elseif app.GreenButton.Value==1
                plotLine1.Color = 'g';
             
            elseif app.CircleButton.Value==1
                plotLine1.Color = 'cyan';
             
            else
                plotLine1.Color = 'k'; 
            end
            
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Configure x- and y- axis
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [-15 15];
            
    
            % Create sine axes
            app.Line = plot(app.UIAxes,app.UIFigure);
            
            app.AmplitudeSlider.Value=0;
            app.FrequencySlider.Value=0;
            app.VerticalShiftSlider.Value=0;
            app.PhaseShiftSlider.Value=0;
             
            app.EditFieldAmplitude.Value = app.AmplitudeSlider.Value;
            app.EditFieldFrequency.Value = app.FrequencySlider.Value;
            app.EditFieldPhaseShift.Value = app.PhaseShiftSlider.Value;
            app.EditFieldVerticalShift.Value = app.VerticalShiftSlider.Value;
            
            % Plot the polar function
            updateplot(app);
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Plot the sine function
            updateplot(app);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
                % Plot the sine function
            updateplot(app);
        end

        % Value changed function: VerticalShiftSlider
        function VerticalShiftSliderValueChanged(app, event)
              % Plot the sine function
            updateplot(app);
        end

        % Callback function
        function ColorDropDownValueChanged(app, event)
            % Plot the sine function
            updateplot(app);
        end

        % Value changed function: EditFieldVerticalShift
        function EditFieldVerticalShiftValueChanged(app, event)
       % Plot the sine function
            updateplot(app);
            
        end

        % Value changed function: EditFieldAmplitude
        function EditFieldAmplitudeValueChanged(app, event)
          % Plot the sine function
            updateplot(app);
        end

        % Value changed function: EditFieldPhaseShift
        function EditFieldPhaseShiftValueChanged(app, event)
          % Plot the sine function
            updateplot(app);
            
        end

        % Value changed function: EditFieldFrequency
        function EditFieldFrequencyValueChanged(app, event)
            % Plot the sine function
            updateplot(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sine Wave Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PhaseShiftSliderLabel = uilabel(app.UIFigure);
            app.PhaseShiftSliderLabel.Text = 'Phase Shift';
            app.PhaseShiftSlider = uislider(app.UIFigure);
            app.PhaseShiftSlider.Limits = [-10 10];
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [-10 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.VerticalShiftSliderLabel = uilabel(app.UIFigure);
            app.VerticalShiftSliderLabel.Text = 'Vertical Shift';
            app.VerticalShiftSlider = uislider(app.UIFigure);
            app.VerticalShiftSlider.Limits = [-10 10];
            app.VerticalShiftSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalShiftSliderValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [-10 10];
            app.SINEWAVEPLOTTERLabel = uilabel(app.UIFigure);
            app.SINEWAVEPLOTTERLabel.Text = '                                                                           SINE  WAVE  PLOTTER';
            app.PARAMETERSLabel = uilabel(app.UIFigure);
            app.PARAMETERSLabel.Text = '                  PARAMETERS';
            app.PLOTLabel = uilabel(app.UIFigure);
            app.PLOTLabel.Text = '                              PLOT';
            app.ColorButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorButtonGroup.Title = 'Color';
            app.BlueButton = uiradiobutton(app.ColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.BlueButton.Value = true;
            app.RedButton = uiradiobutton(app.ColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.GreenButton = uiradiobutton(app.ColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.CircleButton = uiradiobutton(app.ColorButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.ScatterButton = uiradiobutton(app.ColorButtonGroup);
            app.ScatterButton.Text = 'Scatter';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'y = ';
            app.EditFieldAmplitude = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldAmplitude.RoundFractionalValues = 'on';
            app.EditFieldAmplitude.ValueChangedFcn = createCallbackFcn(app, @EditFieldAmplitudeValueChanged, true);
            app.BaseLabel_2 = uilabel(app.UIFigure);
            app.BaseLabel_2.Text = 'sin(               )';
            app.EditFieldFrequency = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldFrequency.RoundFractionalValues = 'on';
            app.EditFieldFrequency.ValueChangedFcn = createCallbackFcn(app, @EditFieldFrequencyValueChanged, true);
            app.BaseLabel_3 = uilabel(app.UIFigure);
            app.BaseLabel_3.Text = 'x';
            app.BaseLabel_4 = uilabel(app.UIFigure);
            app.BaseLabel_4.Text = '+';
            app.BaseLabel_5 = uilabel(app.UIFigure);
            app.BaseLabel_5.Text = '+';
            app.EditFieldPhaseShift = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldPhaseShift.RoundFractionalValues = 'on';
            app.EditFieldPhaseShift.ValueChangedFcn = createCallbackFcn(app, @EditFieldPhaseShiftValueChanged, true);
            app.EditFieldVerticalShift = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldVerticalShift.RoundFractionalValues = 'on';
            app.EditFieldVerticalShift.ValueChangedFcn = createCallbackFcn(app, @EditFieldVerticalShiftValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sine_wave_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end